classdef track < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageAnalysisUIFigure         matlab.ui.Figure
        DatasetpathEditFieldLabel     matlab.ui.control.Label
        DatasetpathEditField          matlab.ui.control.EditField
        HoveroverfieldformoreinformationLabel  matlab.ui.control.Label
        GridtrackingLabel             matlab.ui.control.Label
        pickfolderButton              matlab.ui.control.Button
        pickfolderButton_2            matlab.ui.control.Button
        OutputpathEditFieldLabel      matlab.ui.control.Label
        OutputpathEditField           matlab.ui.control.EditField
        TemplateEditFieldLabel        matlab.ui.control.Label
        TemplateEditField             matlab.ui.control.EditField
        pickfileButton                matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        ImagingspeedEditFieldLabel    matlab.ui.control.Label
        ImagingspeedEditField         matlab.ui.control.NumericEditField
        ScalefactorEditFieldLabel     matlab.ui.control.Label
        ScalefactorEditField          matlab.ui.control.NumericEditField
        NumberofframesEditFieldLabel  matlab.ui.control.Label
        NumberofframesEditField       matlab.ui.control.NumericEditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: pickfolderButton
        function pickfolderButtonPushed(app, event)
            d = uigetdir('.', 'Select dataset folder');
            if d
                app.DatasetpathEditField.Value = d;
            else
                app.DatasetpathEditField.Value = '';
            end
            DatasetpathEditFieldValueChanged(app, event)
        end

        % Button pushed function: pickfolderButton_2
        function pickfolderButton_2Pushed(app, event)
            d = uigetdir('.', 'Select output folder');
            if d
                app.OutputpathEditField.Value = d;
            else
                app.OutputpathEditField.Value = '';
            end
            DatasetpathEditFieldValueChanged(app, event)
        end

        % Button pushed function: pickfileButton
        function pickfileButtonPushed(app, event)
            [d,f] = uigetfile('*.tif','Select template');
            if f
                app.TemplateEditField.Value = fullfile(f,d);
            else
                app.TemplateEditField.Value = '';
            end
            DatasetpathEditFieldValueChanged(app, event)
        end

        % Callback function: DatasetpathEditField, 
        % DatasetpathEditField, OutputpathEditField, 
        % OutputpathEditField, TemplateEditField, TemplateEditField
        function DatasetpathEditFieldValueChanged(app, event)
            path = app.DatasetpathEditField.Value;
            outpath = app.OutputpathEditField.Value;
            template = app.TemplateEditField.Value;
            if isfolder(path) && isfolder(outpath) && isfile(template)
                app.StartButton.Enable = 'on';
            else
                app.StartButton.Enable = 'off';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            path = app.DatasetpathEditField.Value;
            outpath = app.OutputpathEditField.Value;
            template = app.TemplateEditField.Value;
            if isfolder(path) && isfolder(outpath) && isfile(template)
                
                pix_per_micron = app.ScalefactorEditField.Value;
                sec_per_frame = app.ImagingspeedEditField.Value;
                numFrames = app.NumberofframesEditField.Value;
                
                Main_Batch_7x7f(path, outpath, template, pix_per_micron,sec_per_frame, numFrames);
                
            else
                app.StartButton.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageAnalysisUIFigure = uifigure('Visible', 'off');
            app.ImageAnalysisUIFigure.Name = 'Image Analysis';
            app.DatasetpathEditFieldLabel = uilabel(app.ImageAnalysisUIFigure);
            app.DatasetpathEditFieldLabel.Tooltip = {'Path to the folder containing all stacks to be tracked'};
            app.DatasetpathEditFieldLabel.Text = 'Dataset path';
            app.DatasetpathEditField = uieditfield(app.ImageAnalysisUIFigure, 'text');
            app.DatasetpathEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetpathEditFieldValueChanged, true);
            app.DatasetpathEditField.ValueChangingFcn = createCallbackFcn(app, @DatasetpathEditFieldValueChanged, true);
            app.DatasetpathEditField.Tooltip = {'Path to the folder containing all stacks to be tracked'};
            app.HoveroverfieldformoreinformationLabel = uilabel(app.ImageAnalysisUIFigure);
            app.HoveroverfieldformoreinformationLabel.Text = 'Hover over field for more information';
            app.GridtrackingLabel = uilabel(app.ImageAnalysisUIFigure);
            app.GridtrackingLabel.Text = 'Grid tracking';
            app.pickfolderButton = uibutton(app.ImageAnalysisUIFigure, 'push');
            app.pickfolderButton.ButtonPushedFcn = createCallbackFcn(app, @pickfolderButtonPushed, true);
            app.pickfolderButton.Tooltip = {'Path to the folder containing all stacks to be tracked'};
            app.pickfolderButton.Text = 'pick folder';
            app.pickfolderButton_2 = uibutton(app.ImageAnalysisUIFigure, 'push');
            app.pickfolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @pickfolderButton_2Pushed, true);
            app.pickfolderButton_2.Text = 'pick folder';
            app.OutputpathEditFieldLabel = uilabel(app.ImageAnalysisUIFigure);
            app.OutputpathEditFieldLabel.Tooltip = {'Path to which results will be written'};
            app.OutputpathEditFieldLabel.Text = 'Output path';
            app.OutputpathEditField = uieditfield(app.ImageAnalysisUIFigure, 'text');
            app.OutputpathEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetpathEditFieldValueChanged, true);
            app.OutputpathEditField.ValueChangingFcn = createCallbackFcn(app, @DatasetpathEditFieldValueChanged, true);
            app.OutputpathEditField.Tooltip = {'Path to which results will be written'};
            app.TemplateEditFieldLabel = uilabel(app.ImageAnalysisUIFigure);
            app.TemplateEditFieldLabel.Tooltip = {'template used in tracking'};
            app.TemplateEditFieldLabel.Text = 'Template';
            app.TemplateEditField = uieditfield(app.ImageAnalysisUIFigure, 'text');
            app.TemplateEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetpathEditFieldValueChanged, true);
            app.TemplateEditField.ValueChangingFcn = createCallbackFcn(app, @DatasetpathEditFieldValueChanged, true);
            app.TemplateEditField.Tooltip = {'template used in tracking'};
            app.pickfileButton = uibutton(app.ImageAnalysisUIFigure, 'push');
            app.pickfileButton.ButtonPushedFcn = createCallbackFcn(app, @pickfileButtonPushed, true);
            app.pickfileButton.Tooltip = {'template used in tracking'};
            app.pickfileButton.Text = 'pick file';
            app.StartButton = uibutton(app.ImageAnalysisUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Tooltip = {'Start tracking'};
            app.StartButton.Text = 'Start';
            app.ImagingspeedEditFieldLabel = uilabel(app.ImageAnalysisUIFigure);
            app.ImagingspeedEditFieldLabel.Tooltip = {'Imaging speed in seconds per frame'};
            app.ImagingspeedEditFieldLabel.Text = 'Imaging speed';
            app.ImagingspeedEditField = uieditfield(app.ImageAnalysisUIFigure, 'numeric');
            app.ImagingspeedEditField.Tooltip = {'Imaging speed in seconds per frame'};
            app.ImagingspeedEditField.Value = 0.31;
            app.ScalefactorEditFieldLabel = uilabel(app.ImageAnalysisUIFigure);
            app.ScalefactorEditFieldLabel.Tooltip = {'Scale factor in pixels per micron'};
            app.ScalefactorEditFieldLabel.Text = 'Scale factor';
            app.ScalefactorEditField = uieditfield(app.ImageAnalysisUIFigure, 'numeric');
            app.ScalefactorEditField.Tooltip = {'Scale factor in pixels per micron'};
            app.ScalefactorEditField.Value = 9.26;
            app.NumberofframesEditFieldLabel = uilabel(app.ImageAnalysisUIFigure);
            app.NumberofframesEditFieldLabel.Tooltip = {'Number of frames to track - leave at 0 to track entire stack'};
            app.NumberofframesEditFieldLabel.Text = 'Number of frames';
            app.NumberofframesEditField = uieditfield(app.ImageAnalysisUIFigure, 'numeric');
            app.NumberofframesEditField.Tooltip = {'Number of frames to track - leave at 0 to track entire stack'};
            % Show the figure after all components are created
            app.ImageAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = track

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageAnalysisUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageAnalysisUIFigure)
        end
    end
end