classdef analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageAnalysisUIFigure      matlab.ui.Figure
        DatasetpathEditFieldLabel  matlab.ui.control.Label
        DatasetpathEditField       matlab.ui.control.EditField
        HoveroverfieldformoreinformationLabel  matlab.ui.control.Label
        ImageanalysisLabel         matlab.ui.control.Label
        pickfolderButton           matlab.ui.control.Button
        pickfolderButton_2         matlab.ui.control.Button
        OutputpathEditFieldLabel   matlab.ui.control.Label
        OutputpathEditField        matlab.ui.control.EditField
        AnalysisEditFieldLabel     matlab.ui.control.Label
        AnalysisEditField          matlab.ui.control.EditField
        pickfileButton             matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: pickfolderButton
        function pickfolderButtonPushed(app, event)
            d = uigetdir();
            if d
                app.DatasetpathEditField.Value = d;
            else
                app.DatasetpathEditField.Value = '';
            end
            DatasetpathEditFieldValueChanged(app, event)
        end

        % Button pushed function: pickfolderButton_2
        function pickfolderButton_2Pushed(app, event)
            d = uigetdir();
            if d
                app.OutputpathEditField.Value = d;
            else
                app.OutputpathEditField.Value = '';
            end
            DatasetpathEditFieldValueChanged(app, event)
        end

        % Button pushed function: pickfileButton
        function pickfileButtonPushed(app, event)
            [d,f] = uigetfile();
            if f
                app.AnalysisEditField.Value = fullfile(f,d);
            else
                app.AnalysisEditField.Value = '';
            end
            DatasetpathEditFieldValueChanged(app, event)
        end

        % Callback function: AnalysisEditField, AnalysisEditField, 
        % DatasetpathEditField, DatasetpathEditField, 
        % OutputpathEditField, OutputpathEditField
        function DatasetpathEditFieldValueChanged(app, event)
            path = app.DatasetpathEditField.Value;
            outpath = app.OutputpathEditField.Value;
            analysis = app.AnalysisEditField.Value;
            if isfolder(path) && isfolder(outpath) && isfile(analysis)
                app.StartButton.Enable = 'on';
            else
                app.StartButton.Enable = 'off';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            path = app.DatasetpathEditField.Value;
            outpath = app.OutputpathEditField.Value;
            analysis = app.AnalysisEditField.Value;
            if isfolder(path) && isfolder(outpath) && isfile(analysis)
                load(analysis,'-mat', 'spec');
                processDatasets(path,spec,outpath);
            else
                app.StartButton.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageAnalysisUIFigure = uifigure('Visible', 'off');
            app.ImageAnalysisUIFigure.Name = 'Image Analysis';
            app.DatasetpathEditFieldLabel = uilabel(app.ImageAnalysisUIFigure);
            app.DatasetpathEditFieldLabel.Tooltip = {'Path to the folder containing all dataset folders to be analyized'};
            app.DatasetpathEditFieldLabel.Text = 'Dataset path';
            app.DatasetpathEditField = uieditfield(app.ImageAnalysisUIFigure, 'text');
            app.DatasetpathEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetpathEditFieldValueChanged, true);
            app.DatasetpathEditField.ValueChangingFcn = createCallbackFcn(app, @DatasetpathEditFieldValueChanged, true);
            app.DatasetpathEditField.Tooltip = {'Path to the folder containing all dataset folders to be analyized'};
            app.HoveroverfieldformoreinformationLabel = uilabel(app.ImageAnalysisUIFigure);
            app.HoveroverfieldformoreinformationLabel.Text = 'Hover over field for more information';
            app.ImageanalysisLabel = uilabel(app.ImageAnalysisUIFigure);
            app.ImageanalysisLabel.Text = 'Image analysis';
            app.pickfolderButton = uibutton(app.ImageAnalysisUIFigure, 'push');
            app.pickfolderButton.ButtonPushedFcn = createCallbackFcn(app, @pickfolderButtonPushed, true);
            app.pickfolderButton.Text = 'pick folder';
            app.pickfolderButton_2 = uibutton(app.ImageAnalysisUIFigure, 'push');
            app.pickfolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @pickfolderButton_2Pushed, true);
            app.pickfolderButton_2.Text = 'pick folder';
            app.OutputpathEditFieldLabel = uilabel(app.ImageAnalysisUIFigure);
            app.OutputpathEditFieldLabel.Tooltip = {'Path to which results will be written'};
            app.OutputpathEditFieldLabel.Text = 'Output path';
            app.OutputpathEditField = uieditfield(app.ImageAnalysisUIFigure, 'text');
            app.OutputpathEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetpathEditFieldValueChanged, true);
            app.OutputpathEditField.ValueChangingFcn = createCallbackFcn(app, @DatasetpathEditFieldValueChanged, true);
            app.OutputpathEditField.Tooltip = {'Path to which results will be written'};
            app.AnalysisEditFieldLabel = uilabel(app.ImageAnalysisUIFigure);
            app.AnalysisEditFieldLabel.Tooltip = {'analysis specification file to be used'};
            app.AnalysisEditFieldLabel.Text = 'Analysis';
            app.AnalysisEditField = uieditfield(app.ImageAnalysisUIFigure, 'text');
            app.AnalysisEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetpathEditFieldValueChanged, true);
            app.AnalysisEditField.ValueChangingFcn = createCallbackFcn(app, @DatasetpathEditFieldValueChanged, true);
            app.AnalysisEditField.Tooltip = {'analysis specification file to be used'};
            app.pickfileButton = uibutton(app.ImageAnalysisUIFigure, 'push');
            app.pickfileButton.ButtonPushedFcn = createCallbackFcn(app, @pickfileButtonPushed, true);
            app.pickfileButton.Text = 'pick file';
            app.StartButton = uibutton(app.ImageAnalysisUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Tooltip = {'Commence analysis'};
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.ImageAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageAnalysisUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageAnalysisUIFigure)
        end
    end
end